classdef image_filtering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConvolutionMenu               matlab.ui.container.Menu
        ImageSmoothingMenu            matlab.ui.container.Menu
        FreqdomainFilteringMenu       matlab.ui.container.Menu
        FreqdomainBrighteningMenu     matlab.ui.container.Menu
        NoiseFilteringMenu            matlab.ui.container.Menu
        PeriodicNoiseRestorationMenu  matlab.ui.container.Menu
        MotionBlurMenu                matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        ExecuteButton                 matlab.ui.control.Button
        LogArea                       matlab.ui.control.TextArea
        SystemLogTextAreaLabel        matlab.ui.control.Label
        InfoMask                      matlab.ui.control.Label
        SelectedImageLabel            matlab.ui.control.Label
        BrowseButtonLabel             matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        InputMaskEditField            matlab.ui.control.EditField
        InputMaskEditFieldLabel       matlab.ui.control.Label
        ConfigLabel                   matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        ResultImage                   matlab.ui.control.Image
        ResultImageLabel              matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        InputImage                    matlab.ui.control.Image
        InputImageLabel               matlab.ui.control.Label
    end

    properties (Access = public)
        IMAGE_INPUT = [];
        IMAGE_RESULT = [];
        MASK_VALUE_STR = "";
        MASK_VALUE
        N_MASK
        LOG_STR = "";
    end
    
    methods (Access = public)
        function results = append_string_newline_and_print(app, str_initial, str_append)
            results = str_initial + newline + str_append;
            app.LogArea.Value = results;
            drawnow;
        end

        function display_image_on_ui(~, img, ui)
            if (size(img, 3) == 1)
                grayImage = img;
                image_to_display = cat(3, grayImage, grayImage, grayImage);
            else
                image_to_display = img;
            end
            ui.ImageSource = image_to_display;
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ConvolutionMenu
        function ConvolutionMenuSelected(app, event)
            app.ConfigLabel.Text = 'Convolution';
        end

        % Menu selected function: ImageSmoothingMenu
        function ImageSmoothingMenuSelected(app, event)
            app.ConfigLabel.Text = 'Image Smoothing';
        end

        % Menu selected function: FreqdomainFilteringMenu
        function FreqdomainFilteringMenuSelected(app, event)
            app.ConfigLabel.Text = 'Freq-domain Filtering';
        end

        % Menu selected function: FreqdomainBrighteningMenu
        function FreqdomainBrighteningMenuSelected(app, event)
            app.ConfigLabel.Text = 'Freq-domain Brightening';
        end

        % Menu selected function: NoiseFilteringMenu
        function NoiseFilteringMenuSelected(app, event)
            app.ConfigLabel.Text = 'Noise Filtering';
        end

        % Menu selected function: PeriodicNoiseRestorationMenu
        function PeriodicNoiseRestorationMenuSelected(app, event)
            app.ConfigLabel.Text = 'Periodic Noise Restoration';
        end

        % Menu selected function: MotionBlurMenu
        function MotionBlurMenuSelected(app, event)
            app.ConfigLabel.Text = 'Motion Blur';
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
         
            % Open file dialog to browse for an image file
            [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            
            % If the user selected a file, display it in the UIAxes
            if ischar(fileName)
                % Full file path of the selected image
                fullFileName = fullfile(filePath, fileName);

                % Change label
                app.SelectedImageLabel.Text = fileName;
                app.IMAGE_INPUT= imread(fullFileName);

                display_image_on_ui(app, app.IMAGE_INPUT, app.InputImage);
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            addpath("functions\matrix\");
            addpath("functions\programs\");
            app.LOG_STR = ""; % Reset System Log
            app.LogArea.Value = "";

            if (isempty(app.IMAGE_INPUT))
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[FAILED] No image is inputted!");
                return;
            end

            if (app.ConfigLabel.Text == "Convolution")
                if (isempty(app.MASK_VALUE_STR))
                    app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[FAILED] Mask is empty!");
                    return;
                end

                [app.MASK_VALUE, app.N_MASK] = reshape_mask(app.MASK_VALUE_STR);
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[INITIATE] Convolution Process");
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[PROCESS] Process might take time. Please wait...");
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR,  display_mat(app.MASK_VALUE));
                app.IMAGE_RESULT = convolution(app.IMAGE_INPUT, app.MASK_VALUE, app.N_MASK);
                
                display_image_on_ui(app, app.IMAGE_RESULT, app.ResultImage);

            elseif (app.ConfigLabel.Text == "Image Smoothing")
                app.LOG_STR = "[INITIATE] Image Smoothing Process";
            elseif (app.ConfigLabel.Text == "Freq-domain Filtering")
                app.LOG_STR = "[INITIATE] Freq-domain Filtering Process";
            elseif (app.ConfigLabel.Text == "Freq-domain Brightening")
                app.LOG_STR = "[INITIATE] Freq-domain Brightening Process";
            elseif (app.ConfigLabel.Text == "Noise Filtering")
                app.LOG_STR = "[INITIATE] Noise Filtering Process";
            elseif (app.ConfigLabel.Text == "Periodic Noise Restoration")
                app.LOG_STR = "[INITIATE] Periodic Noise Restoration Process";
            else % (app.ConfigLabel.Text == "Motion Blur")
                app.LOG_STR = "[INITIATE] Motion Blur Process";
            end

            app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[FINISHED] Process has finished!");
        end

        % Value changed function: InputMaskEditField
        function InputMaskEditFieldValueChanged(app, event)
            addpath("functions\utils\");
            app.MASK_VALUE_STR = app.InputMaskEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConvolutionMenu = uimenu(app.UIFigure);
            app.ConvolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvolutionMenuSelected, true);
            app.ConvolutionMenu.Text = 'Convolution';
            app.ImageSmoothingMenu = uimenu(app.UIFigure);
            app.ImageSmoothingMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageSmoothingMenuSelected, true);
            app.ImageSmoothingMenu.Text = 'Image Smoothing';
            app.FreqdomainFilteringMenu = uimenu(app.UIFigure);
            app.FreqdomainFilteringMenu.MenuSelectedFcn = createCallbackFcn(app, @FreqdomainFilteringMenuSelected, true);
            app.FreqdomainFilteringMenu.Text = 'Freq-domain Filtering';
            app.FreqdomainBrighteningMenu = uimenu(app.UIFigure);
            app.FreqdomainBrighteningMenu.MenuSelectedFcn = createCallbackFcn(app, @FreqdomainBrighteningMenuSelected, true);
            app.FreqdomainBrighteningMenu.Text = 'Freq-domain Brightening';
            app.NoiseFilteringMenu = uimenu(app.UIFigure);
            app.NoiseFilteringMenu.MenuSelectedFcn = createCallbackFcn(app, @NoiseFilteringMenuSelected, true);
            app.NoiseFilteringMenu.Text = 'Noise Filtering';
            app.PeriodicNoiseRestorationMenu = uimenu(app.UIFigure);
            app.PeriodicNoiseRestorationMenu.MenuSelectedFcn = createCallbackFcn(app, @PeriodicNoiseRestorationMenuSelected, true);
            app.PeriodicNoiseRestorationMenu.Text = 'Periodic Noise Restoration';
            app.MotionBlurMenu = uimenu(app.UIFigure);
            app.MotionBlurMenu.MenuSelectedFcn = createCallbackFcn(app, @MotionBlurMenuSelected, true);
            app.MotionBlurMenu.Text = 'Motion Blur';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.InputImageLabel = uilabel(app.GridLayout3);
            app.InputImageLabel.Text = 'Input Image';
            app.InputImage = uiimage(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.ResultImageLabel = uilabel(app.GridLayout4);
            app.ResultImageLabel.Text = 'Result Image';
            app.ResultImage = uiimage(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.ConfigLabel = uilabel(app.GridLayout5);
            app.ConfigLabel.Text = 'Convolution';
            app.InputMaskEditFieldLabel = uilabel(app.GridLayout5);
            app.InputMaskEditFieldLabel.Text = 'Input Mask';
            app.InputMaskEditField = uieditfield(app.GridLayout5, 'text');
            app.InputMaskEditField.ValueChangedFcn = createCallbackFcn(app, @InputMaskEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.GridLayout5, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.BrowseButtonLabel = uilabel(app.GridLayout5);
            app.BrowseButtonLabel.Text = 'Input Image';
            app.SelectedImageLabel = uilabel(app.GridLayout5);
            app.SelectedImageLabel.Text = 'No image has been chosen';
            app.InfoMask = uilabel(app.GridLayout5);
            app.InfoMask.Text = 'Seperated by space e.g.: 0 1 0 1 2 1 0 1 0';
            app.SystemLogTextAreaLabel = uilabel(app.GridLayout5);
            app.SystemLogTextAreaLabel.Text = 'System Log';
            app.LogArea = uitextarea(app.GridLayout5);
            app.ExecuteButton = uibutton(app.GridLayout5, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_filtering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end